classdef Proc_FilterSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        Panel                     matlab.ui.container.Panel
        FilterSettingsGridLayout  matlab.ui.container.GridLayout
        NotchInput                matlab.ui.control.DropDown
        UnitLabel3                matlab.ui.control.Label
        UnitLabel1                matlab.ui.control.Label
        DashLabel                 matlab.ui.control.Label
        FilterOrderValue          matlab.ui.control.Label
        FilterOrderLabel          matlab.ui.control.Label
        TransitionBandwidthInput  matlab.ui.control.NumericEditField
        TransitionbandwidthEditFieldLabel  matlab.ui.control.Label
        WindowTypeInput           matlab.ui.control.DropDown
        WindowtypeDropDownLabel   matlab.ui.control.Label
        DoNotchInput              matlab.ui.control.CheckBox
        LowpassInput              matlab.ui.control.NumericEditField
        HighpassInput             matlab.ui.control.NumericEditField
        DoBandpassInput           matlab.ui.control.CheckBox
        UIAxesMagnitude           matlab.ui.control.UIAxes
        UIAxesPhase               matlab.ui.control.UIAxes
        OKButton                  matlab.ui.control.Button
        CancelButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainApp;
        InterruptDialog;
        LineMagnitude;
        LinePhase;
        % Structure that keeps the state
        State = struct();
    end
    
    methods (Access = public)
        % =========================================================
        % FUNCTION: Initializes a new Empty State
        function InitializeState(app, Settings)
            app.State = Settings;
            app.State.FilterOrder = pop_firwsord(lower(app.State.WindowType), app.State.Fs, app.State.TransitionBW);
            app.DoBandpassInput.Value = app.State.DoBandpass;
            app.DoNotchInput.Value = app.State.DoNotch;
            app.HighpassInput.Value = app.State.Highpass;
            app.LowpassInput.Value = app.State.Lowpass;
            app.NotchInput.Value = num2str(app.State.Notch);
            app.WindowTypeInput.Value = app.State.WindowType;
            app.TransitionBandwidthInput.Value = app.State.TransitionBW;
            app.FilterOrderValue.Text = num2str(app.State.FilterOrder);
        end
        % =========================================================
        function c = ParseFilterSettings(app)
            c = [{app.State.FilterOrder} {sort([app.State.Highpass, app.State.Lowpass] / (app.State.Fs / 2))}]; % Sorting and normalization
            c = [c {windows(lower(app.State.WindowType), app.State.FilterOrder + 1)'}];
        end
        % =========================================================
        function [f, db] = GetMagnitudeResponse(~, b, nfft, fs, causal)
            if nargin < 5 || isempty(causal)
                causal = 0;
            end
            if nargin < 3 || isempty(nfft)
                nfft = 2^fix(log2(length(b)));
                if nfft < 512
                    nfft = 512;
                end
            end
            f = (0:1 / nfft:1) * fs / 2;
            % Frequency response
            z = fft(b, nfft * 2);
            z = z(1:fix(length(z) / 2) + 1);
            % Magnitude response
            db = abs(z);
            db(db < eps^(2 / 3)) = eps^(2 / 3); % Log of zero warning
            db = 20 * log10(db);
        end
        % =========================================================
        function [f, phi] = GetPhaseResponse(~, b, nfft, fs, causal)
            if nargin < 5 || isempty(causal)
                causal = 0;
            end
            if nargin < 3 || isempty(nfft)
                nfft = 2^fix(log2(length(b)));
                if nfft < 512
                    nfft = 512;
                end
            end
            f = (0:1 / nfft:1) * fs / 2;
            % Frequency response
            m = fix((length(b) - 1) / 2); % Filter order
            z = fft(b, nfft * 2);
            z = z(1:fix(length(z) / 2) + 1);
            % Phase response
            z(abs(z) < eps^(2 / 3)) = NaN; % Phase is undefined for magnitude zero
            phi = angle(z);
            if causal
                phi = unwrap(phi);
            else
                delay = -mod((0:1 / nfft:1) * m * pi + pi, 2 * pi) + pi; % Zero-phase
                phi = phi - delay;
                phi = phi + 2 * pi * (phi <= -pi + eps ^ (1/3)); % Unwrap
            end
        end
        % =========================================================
        % MAIN FUNCTION
        % =========================================================
        % FUNCTION: Map the State to the Properties of all Components
        function MapStateToProps(app)
            % ---------------------------------------------------------
            % Set Bandpass 'Enable'
            UpdateProperty(app, app.HighpassInput, 'Enable', ifelse(app.State.DoBandpass, 'on', 'off'));
            UpdateProperty(app, app.LowpassInput, 'Enable', ifelse(app.State.DoBandpass, 'on', 'off'));
            UpdateProperty(app, app.DashLabel, 'Enable', ifelse(app.State.DoBandpass, 'on', 'off'));
            UpdateProperty(app, app.UnitLabel1, 'Enable', ifelse(app.State.DoBandpass, 'on', 'off'));
            % ---------------------------------------------------------
            % Set Notch 'Enable'
            UpdateProperty(app, app.NotchInput, 'Enable', ifelse(app.State.DoNotch, 'on', 'off'));
            % ---------------------------------------------------------
            % Set Settings 'Enable'
            UpdateProperty(app, app.WindowTypeInput, 'Enable', ifelse(app.State.DoBandpass || app.State.DoNotch, 'on', 'off'));
            UpdateProperty(app, app.TransitionBandwidthInput, 'Enable', ifelse(app.State.DoBandpass || app.State.DoNotch, 'on', 'off'));
            UpdateProperty(app, app.UnitLabel3, 'Enable', ifelse(app.State.DoBandpass || app.State.DoNotch, 'on', 'off'));
            UpdateProperty(app, app.FilterOrderValue, 'Enable', ifelse(app.State.DoBandpass || app.State.DoNotch, 'on', 'off'));
            % ---------------------------------------------------------
            % Set lowpass upper limit
            UpdateProperty(app, app.HighpassInput, 'Limits', [app.State.TransitionBW/2, app.State.Lowpass-app.State.TransitionBW*2-0.1]);
            app.State.Highpass = app.HighpassInput.Value; % Make sure if the value changed, this also changed in the state
            UpdateProperty(app, app.LowpassInput, 'Limits', [app.State.Highpass+app.State.TransitionBW*2, app.State.Fs/2-app.State.TransitionBW/2]);
            app.State.Lowpass = app.LowpassInput.Value; % Make sure if the value changed, this also changed in the state
            % ---------------------------------------------------------
            % Set TransitionBW limits
            UpdateProperty(app, app.TransitionBandwidthInput, 'Limits', [0.1, min([app.State.Highpass*2, app.State.Fs/2-app.State.Lowpass, app.State.Lowpass-app.State.Highpass])]);
            UpdateProperty(app, app.TransitionBandwidthInput, 'Value', app.State.TransitionBW);
            app.State.TransitionBW = app.TransitionBandwidthInput.Value;  % Make sure if the value changed, this also changed in the state
            % ---------------------------------------------------------
            % Set Filter Order
            UpdateProperty(app, app.FilterOrderValue, 'Text', num2str(app.State.FilterOrder));
            % ---------------------------------------------------------
            % Plot Magnitude and Phase
            if app.State.DoBandpass
                c = ParseFilterSettings(app);
                b = firws(c{:});
                [XData, YData] = app.GetMagnitudeResponse(b, [], app.State.Fs, 0);
                UpdateProperty(app, app.LineMagnitude, 'XData', XData);
                UpdateProperty(app, app.LineMagnitude, 'YData', YData);
                [XData, YData] = app.GetPhaseResponse(b, [], app.State.Fs, 0);
                UpdateProperty(app, app.LinePhase, 'XData', XData);
                UpdateProperty(app, app.LinePhase, 'YData', YData);
            else
                UpdateProperty(app, app.LineMagnitude, 'XData', -1);
                UpdateProperty(app, app.LineMagnitude, 'YData', 0);
                UpdateProperty(app, app.LinePhase, 'XData', -1);
                UpdateProperty(app, app.LinePhase, 'YData', 0);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, Settings)
            % ---------------------------------------------------------
            % Store main app object
            if exist('MainApp', 'var')
                app.MainApp = MainApp;
                % Position the new app relative to the main app
                app_positionGUI(app.MainApp, app.UIFigure)
                % Show waitbar to indicate another gui is open
                app.InterruptDialog = uiprogressdlg(app.MainApp.UIFigure);
            else
                app.MainApp = struct();
            end
            % Set axes XLim
            app.UIAxesMagnitude.XLim = [0, Settings.Fs/2];
            app.UIAxesPhase.XLim = [0, Settings.Fs/2];
            app.LineMagnitude = plot(app.UIAxesMagnitude,-1, 0, '-', 'LineWidth', 1, 'PickableParts', 'none');
            app.LinePhase = plot(app.UIAxesPhase, -1, 0, '-', 'LineWidth', 1, 'PickableParts', 'none');
            % Clear the import structure
            app.MainApp.Import = struct();
            app.MainApp.Import.Return = false;
            % ---------------------------------------------------------
            % Initialize State
            app.InitializeState(Settings);
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.MapStateToProps();
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            app.MainApp.Import.Data = app.State;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Value changed function: DoBandpassInput
        function DoBandpassInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.DoBandpass = app.DoBandpassInput.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.MapStateToProps();
        end

        % Value changed function: DoNotchInput
        function DoNotchInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.DoNotch = app.DoNotchInput.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.MapStateToProps();
        end

        % Value changed function: HighpassInput
        function HighpassInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.Highpass = app.HighpassInput.Value;
            if app.State.TransitionBW > app.State.Highpass*2
                app.State.TransitionBW = app.State.Highpass*2;
                app.State.FilterOrder = pop_firwsord(lower(app.State.WindowType), app.State.Fs, app.State.TransitionBW);
            end
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.MapStateToProps();
        end

        % Value changed function: LowpassInput
        function LowpassInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.Lowpass = app.LowpassInput.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.MapStateToProps();
        end

        % Value changed function: NotchInput
        function NotchInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.Notch = str2double(app.NotchInput.Value);
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.MapStateToProps();
        end

        % Value changed function: WindowTypeInput
        function WindowTypeInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.WindowType = app.WindowTypeInput.Value;
            app.State.FilterOrder = pop_firwsord(lower(app.State.WindowType), app.State.Fs, app.State.TransitionBW);
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.MapStateToProps();
        end

        % Value changed function: TransitionBandwidthInput
        function TransitionBandwidthInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.TransitionBW = app.TransitionBandwidthInput.Value;
            app.State.FilterOrder = pop_firwsord(lower(app.State.WindowType), app.State.Fs, app.State.TransitionBW);
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.MapStateToProps();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Filter Settings';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.Panel = uipanel(app.GridLayout);
            app.FilterSettingsGridLayout = uigridlayout(app.Panel);
            app.UIAxesPhase = uiaxes(app.FilterSettingsGridLayout);
            title(app.UIAxesPhase, 'Bandpass Phase Response')
            xlabel(app.UIAxesPhase, 'Frequency (Hz)')
            ylabel(app.UIAxesPhase, 'Phase (rad)')
            app.UIAxesMagnitude = uiaxes(app.FilterSettingsGridLayout);
            title(app.UIAxesMagnitude, 'Bandpass Magnitude Response')
            xlabel(app.UIAxesMagnitude, 'Frequency (Hz)')
            ylabel(app.UIAxesMagnitude, 'Magnitude (dB)')
            app.DoBandpassInput = uicheckbox(app.FilterSettingsGridLayout);
            app.DoBandpassInput.ValueChangedFcn = createCallbackFcn(app, @DoBandpassInputValueChanged, true);
            app.DoBandpassInput.Text = 'Bandpass Filter:';
            app.HighpassInput = uieditfield(app.FilterSettingsGridLayout, 'numeric');
            app.HighpassInput.Limits = [0.1 Inf];
            app.HighpassInput.ValueChangedFcn = createCallbackFcn(app, @HighpassInputValueChanged, true);
            app.HighpassInput.Enable = 'off';
            app.HighpassInput.Value = 0.1;
            app.LowpassInput = uieditfield(app.FilterSettingsGridLayout, 'numeric');
            app.LowpassInput.LowerLimitInclusive = 'off';
            app.LowpassInput.Limits = [0.1 Inf];
            app.LowpassInput.ValueChangedFcn = createCallbackFcn(app, @LowpassInputValueChanged, true);
            app.LowpassInput.Enable = 'off';
            app.LowpassInput.Value = 60;
            app.DoNotchInput = uicheckbox(app.FilterSettingsGridLayout);
            app.DoNotchInput.ValueChangedFcn = createCallbackFcn(app, @DoNotchInputValueChanged, true);
            app.DoNotchInput.Text = 'Notch Filter:';
            app.WindowtypeDropDownLabel = uilabel(app.FilterSettingsGridLayout);
            app.WindowtypeDropDownLabel.Text = 'Window type:';
            app.WindowTypeInput = uidropdown(app.FilterSettingsGridLayout);
            app.WindowTypeInput.Items = {'Rectangular', 'Bartlett', 'Hann', 'Hamming', 'Blackman'};
            app.WindowTypeInput.ValueChangedFcn = createCallbackFcn(app, @WindowTypeInputValueChanged, true);
            app.WindowTypeInput.Enable = 'off';
            app.WindowTypeInput.Value = 'Hamming';
            app.TransitionbandwidthEditFieldLabel = uilabel(app.FilterSettingsGridLayout);
            app.TransitionbandwidthEditFieldLabel.Text = 'Transition bandwidth:';
            app.TransitionBandwidthInput = uieditfield(app.FilterSettingsGridLayout, 'numeric');
            app.TransitionBandwidthInput.Limits = [0.1 Inf];
            app.TransitionBandwidthInput.ValueChangedFcn = createCallbackFcn(app, @TransitionBandwidthInputValueChanged, true);
            app.TransitionBandwidthInput.Enable = 'off';
            app.TransitionBandwidthInput.Value = 0.2;
            app.FilterOrderLabel = uilabel(app.FilterSettingsGridLayout);
            app.FilterOrderLabel.Text = 'Filter order:';
            app.FilterOrderValue = uilabel(app.FilterSettingsGridLayout);
            app.FilterOrderValue.Enable = 'off';
            app.FilterOrderValue.Text = '8250';
            app.DashLabel = uilabel(app.FilterSettingsGridLayout);
            app.DashLabel.Enable = 'off';
            app.DashLabel.Text = '-';
            app.UnitLabel1 = uilabel(app.FilterSettingsGridLayout);
            app.UnitLabel1.Enable = 'off';
            app.UnitLabel1.Text = 'Hz';
            app.UnitLabel3 = uilabel(app.FilterSettingsGridLayout);
            app.UnitLabel3.Enable = 'off';
            app.UnitLabel3.Text = 'Hz';
            app.NotchInput = uidropdown(app.FilterSettingsGridLayout);
            app.NotchInput.Items = {'50 Hz', '60 Hz'};
            app.NotchInput.ItemsData = {'50', '60'};
            app.NotchInput.ValueChangedFcn = createCallbackFcn(app, @NotchInputValueChanged, true);
            app.NotchInput.Enable = 'off';
            app.NotchInput.Value = '50';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proc_FilterSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end