classdef Proc_SpectrogramSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        SettingsPanel                  matlab.ui.container.Panel
        GridLayoutSettingsPanel        matlab.ui.container.GridLayout
        BaselineNormMethodDesc         matlab.ui.control.Label
        BaselineNormMethodInput        matlab.ui.control.DropDown
        DoBaselineNormInput            matlab.ui.control.CheckBox
        CyclesInput                    matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        CyclesLabel                    matlab.ui.control.Label
        MaximumFrequencyInput          matlab.ui.control.NumericEditField
        HzEditField_2Label             matlab.ui.control.Label
        MaximumfrequencyLabel          matlab.ui.control.Label
        FrequencyStepInput             matlab.ui.control.NumericEditField
        HzEditFieldLabel               matlab.ui.control.Label
        FrequencystepLabel             matlab.ui.control.Label
        TimeStepInput                  matlab.ui.control.NumericEditField
        sEditFieldLabel                matlab.ui.control.Label
        TimestepLabel                  matlab.ui.control.Label
        SpectrogramTypeInput           matlab.ui.control.DropDown
        SpectrogramtypeLabel           matlab.ui.control.Label
        SelectChannelsPanel            matlab.ui.container.Panel
        GridLayoutSelectChannelsPanel  matlab.ui.container.GridLayout
        SelNoChansButton               matlab.ui.control.Button
        SelAllChansButton              matlab.ui.control.Button
        SelChanListBox                 matlab.ui.control.ListBox
        UIAxesSelChan                  matlab.ui.control.UIAxes
        OKButton                       matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainApp;
        InterruptDialog;
        LineChans;
        LineSelChans;
        % Structure that keeps the state
        State = struct();
    end
    
    methods (Access = public)
        % =========================================================
        % FUNCTION: Initializes a new Empty State
        function InitializeState(app, Settings, Chanlocs)
            app.State = struct();
            app.State.SpectrogramSettings = Settings;
            app.State.Chanlocs = Chanlocs;
            % ---------------------------------------------------------
            if app.State.SpectrogramSettings.HasBaseline
                app.DoBaselineNormInput.Value = app.State.SpectrogramSettings.DoBaselineNorm;
                app.BaselineNormMethodInput.Value = app.State.SpectrogramSettings.BaselineMethod;
                app.DoBaselineNormInput.Enable = 'on';
                app.BaselineNormMethodInput.Enable = 'on';
                app.BaselineNormMethodDesc.Enable = 'on';
            else
                app.DoBaselineNormInput.Value = false;
                app.BaselineNormMethodInput.Value = app.State.SpectrogramSettings.BaselineMethod;
                app.DoBaselineNormInput.Enable = 'off';
                app.BaselineNormMethodInput.Enable = 'off';
                app.BaselineNormMethodDesc.Enable = 'off';
            end
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            MapStateToProps(app)
        end
        % =========================================================
        % MAIN FUNCTION
        % =========================================================
        % FUNCTION: Map the State to the Properties of all Components
        function MapStateToProps(app)
            app.LineSelChans.XData = app.LineChans.XData(ismember({app.State.Chanlocs.labels}, app.State.SpectrogramSettings.ChanSel));
            app.LineSelChans.YData = app.LineChans.YData(ismember({app.State.Chanlocs.labels}, app.State.SpectrogramSettings.ChanSel));
            app.LineSelChans.ZData = app.LineChans.ZData(ismember({app.State.Chanlocs.labels}, app.State.SpectrogramSettings.ChanSel));
            app.SelChanListBox.Items = app.State.SpectrogramSettings.ChanSel;
            % ---------------------------------------------------------
            if app.State.SpectrogramSettings.HasBaseline && app.State.SpectrogramSettings.DoBaselineNorm
                app.BaselineNormMethodInput.Enable = 'on';
                app.BaselineNormMethodDesc.Enable = 'on';
                switch app.State.SpectrogramSettings.BaselineMethod
                    case 'absolute'
                        app.BaselineNormMethodDesc.Text = 'data = data - mean';
                    case 'relative'
                        app.BaselineNormMethodDesc.Text = 'data = data / mean';
                    case 'relchange'
                        app.BaselineNormMethodDesc.Text = 'data = (data - mean) / mean';
                    case 'normchange'
                        app.BaselineNormMethodDesc.Text = 'data = (data - mean) / (data + mean)';
                    case 'db'
                        app.BaselineNormMethodDesc.Text = 'data = 10 x log10(data / mean)';
                    case 'zscore'
                        app.BaselineNormMethodDesc.Text = 'data = (data - mean) / std(baseline)';
                end
            elseif app.State.SpectrogramSettings.HasBaseline && ~app.State.SpectrogramSettings.DoBaselineNorm
                app.BaselineNormMethodInput.Enable = 'off';
                app.BaselineNormMethodDesc.Enable = 'off';
                app.BaselineNormMethodDesc.Text = 'No baseline normalization';
            else
                app.BaselineNormMethodInput.Enable = 'off';
                app.BaselineNormMethodDesc.Enable = 'off';
                app.BaselineNormMethodDesc.Text = 'No baseline specified';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, Settings, Chanlocs)
            % ---------------------------------------------------------
            % Store main app object
            if exist('MainApp', 'var')
                app.MainApp = MainApp;
                % Position the new app relative to the main app
                app_positionGUI(app.MainApp, app.UIFigure)
                % Show waitbar to indicate another gui is open
                app.InterruptDialog = uiprogressdlg(app.MainApp.UIFigure);
            else
                app.MainApp = struct();
            end
            % Clear the import structure
            app.MainApp.Import = struct();
            app.MainApp.Import.Return = false;
            % ---------------------------------------------------------
            app.UIAxesSelChan.Toolbar = [];
            app.UIAxesSelChan.Interactions = [];
            % ---------------------------------------------------------
            % Plot Chanlocs
            Fig = figure;
            delete(app.UIAxesSelChan.Children)
            topoplot(ones(length(Chanlocs), 1), Chanlocs, ...
                'style', 'map', ...
                'electrodes', 'on', ...
                'plotrad', 0.85, ...
                'headrad', 0.6, ...
                'intrad', 1, ...
                'conv', 'on', ...
                'numcontour', 0, ...
                'emarker', {'.', [0.15, 0.15, 0.15], 8, 1}, ...
                'emarker2', {1, '.', [0.188, 0.310, 0.792], 16, 1}, ...
                'hcolor', [0.75, 0.75, 0.75], ...
                'whitebk', 'on');
            copyobj(findobj(Fig.Children.Children, 'type', 'line'), app.UIAxesSelChan)
            close(Fig)
            app.LineSelChans = findobj(app.UIAxesSelChan.Children, 'MarkerSize', 16);
            app.LineChans = findobj(app.UIAxesSelChan.Children, 'MarkerSize', 8);
            % Add Electrode 1 to the LineChans
            app.LineChans.XData = [app.LineSelChans.XData, app.LineChans.XData];
            app.LineChans.YData = [app.LineSelChans.YData, app.LineChans.YData];
            app.LineChans.ZData = [app.LineSelChans.ZData, app.LineChans.ZData];
            for i = 1:length(app.UIAxesSelChan.Children)
                app.UIAxesSelChan.Children(i).PickableParts = 'none';
            end
            % Set values
            app.SpectrogramTypeInput.Value = Settings.SpectrogramType;
            app.TimeStepInput.Value = Settings.TimeStep;
            app.FrequencyStepInput.Value = Settings.FreqStep;
            app.MaximumFrequencyInput.Value = Settings.MaxFreq;
            app.CyclesInput.Value = Settings.Cycles;
            % ---------------------------------------------------------
            % Initialize State
            app.InitializeState(Settings, Chanlocs);
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.MapStateToProps();
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            app.MainApp.Import.Data = app.State.SpectrogramSettings;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: SelAllChansButton
        function SelAllChansButtonPushed(app, event)
            % ---------------------------------------------------------
            app.State.SpectrogramSettings.ChanSel = {app.State.Chanlocs.labels};
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.MapStateToProps();
        end

        % Button pushed function: SelNoChansButton
        function SelNoChansButtonPushed(app, event)
            % ---------------------------------------------------------
            app.State.SpectrogramSettings.ChanSel = {};
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.MapStateToProps();
        end

        % Value changed function: SpectrogramTypeInput
        function SpectrogramTypeInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.SpectrogramSettings.SpectrogramType = app.SpectrogramTypeInput.Value;
        end

        % Value changed function: TimeStepInput
        function TimeStepInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.SpectrogramSettings.TimeStep = app.TimeStepInput.Value;
        end

        % Value changed function: FrequencyStepInput
        function FrequencyStepInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.SpectrogramSettings.FreqStep = app.FrequencyStepInput.Value;
        end

        % Value changed function: MaximumFrequencyInput
        function MaximumFrequencyInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.SpectrogramSettings.MaxFreq = app.MaximumFrequencyInput.Value;
        end

        % Value changed function: CyclesInput
        function CyclesInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.SpectrogramSettings.Cycles = app.CyclesInput.Value;
        end

        % Value changed function: DoBaselineNormInput
        function DoBaselineNormInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.SpectrogramSettings.DoBaselineNorm = app.DoBaselineNormInput.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.MapStateToProps();
        end

        % Value changed function: BaselineNormMethodInput
        function BaselineNormMethodInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.SpectrogramSettings.BaselineMethod = app.BaselineNormMethodInput.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.MapStateToProps();
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if strcmpi(event.Source.CurrentObject.Tag, 'Topo')
                % ---------------------------------------------------------
                CurrPoint = event.Source.CurrentObject.CurrentPoint(1, :);
                [~, ChanSelIdx] = min((app.LineChans.XData-CurrPoint(1)).^2 + (app.LineChans.YData-CurrPoint(2)).^2);
                ChanSel = app.State.Chanlocs(ChanSelIdx).labels;
                if ismember(ChanSel, app.State.SpectrogramSettings.ChanSel)
                    app.State.SpectrogramSettings.ChanSel(strcmpi(app.State.SpectrogramSettings.ChanSel, ChanSel)) = [];
                else
                    app.State.SpectrogramSettings.ChanSel = [app.State.SpectrogramSettings.ChanSel; {ChanSel}];
                end
                app.MapStateToProps();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Spectrogram Settings';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.SelectChannelsPanel = uipanel(app.GridLayout);
            app.SelectChannelsPanel.Title = 'Select Channels';
            app.GridLayoutSelectChannelsPanel = uigridlayout(app.SelectChannelsPanel);
            app.UIAxesSelChan = uiaxes(app.GridLayoutSelectChannelsPanel);
            app.UIAxesSelChan.XLim = [-0.55 0.55];
            app.UIAxesSelChan.YLim = [-0.45 0.45];
            app.UIAxesSelChan.ZLim = [0 1];
            app.UIAxesSelChan.CLim = [0 3];
            app.UIAxesSelChan.XTick = [];
            app.UIAxesSelChan.XTickLabel = '';
            app.UIAxesSelChan.YTick = [];
            app.UIAxesSelChan.YTickLabel = '';
            app.UIAxesSelChan.ZTick = [];
            app.UIAxesSelChan.ZTickLabel = '';
            app.UIAxesSelChan.Tag = 'Topo';
            app.SelChanListBox = uilistbox(app.GridLayoutSelectChannelsPanel);
            app.SelChanListBox.Items = {};
            app.SelChanListBox.Value = {};
            app.SelAllChansButton = uibutton(app.GridLayoutSelectChannelsPanel, 'push');
            app.SelAllChansButton.ButtonPushedFcn = createCallbackFcn(app, @SelAllChansButtonPushed, true);
            app.SelAllChansButton.Text = 'All';
            app.SelNoChansButton = uibutton(app.GridLayoutSelectChannelsPanel, 'push');
            app.SelNoChansButton.ButtonPushedFcn = createCallbackFcn(app, @SelNoChansButtonPushed, true);
            app.SelNoChansButton.Text = 'None';
            app.SettingsPanel = uipanel(app.GridLayout);
            app.SettingsPanel.Title = 'Settings';
            app.GridLayoutSettingsPanel = uigridlayout(app.SettingsPanel);
            app.SpectrogramtypeLabel = uilabel(app.GridLayoutSettingsPanel);
            app.SpectrogramtypeLabel.Text = 'Spectrogram type:';
            app.SpectrogramTypeInput = uidropdown(app.GridLayoutSettingsPanel);
            app.SpectrogramTypeInput.Items = {'Multitaper', 'Wavelet'};
            app.SpectrogramTypeInput.ValueChangedFcn = createCallbackFcn(app, @SpectrogramTypeInputValueChanged, true);
            app.SpectrogramTypeInput.Value = 'Multitaper';
            app.TimestepLabel = uilabel(app.GridLayoutSettingsPanel);
            app.TimestepLabel.Text = 'Time step:';
            app.sEditFieldLabel = uilabel(app.GridLayoutSettingsPanel);
            app.sEditFieldLabel.Text = 's';
            app.TimeStepInput = uieditfield(app.GridLayoutSettingsPanel, 'numeric');
            app.TimeStepInput.Limits = [0.001 Inf];
            app.TimeStepInput.ValueChangedFcn = createCallbackFcn(app, @TimeStepInputValueChanged, true);
            app.TimeStepInput.Value = 0.05;
            app.FrequencystepLabel = uilabel(app.GridLayoutSettingsPanel);
            app.FrequencystepLabel.Text = 'Frequency step:';
            app.HzEditFieldLabel = uilabel(app.GridLayoutSettingsPanel);
            app.HzEditFieldLabel.Text = 'Hz';
            app.FrequencyStepInput = uieditfield(app.GridLayoutSettingsPanel, 'numeric');
            app.FrequencyStepInput.Limits = [0.1 Inf];
            app.FrequencyStepInput.ValueChangedFcn = createCallbackFcn(app, @FrequencyStepInputValueChanged, true);
            app.FrequencyStepInput.Value = 0.25;
            app.MaximumfrequencyLabel = uilabel(app.GridLayoutSettingsPanel);
            app.MaximumfrequencyLabel.Text = 'Maximum frequency:';
            app.HzEditField_2Label = uilabel(app.GridLayoutSettingsPanel);
            app.HzEditField_2Label.Text = 'Hz';
            app.MaximumFrequencyInput = uieditfield(app.GridLayoutSettingsPanel, 'numeric');
            app.MaximumFrequencyInput.Limits = [0.1 Inf];
            app.MaximumFrequencyInput.ValueChangedFcn = createCallbackFcn(app, @MaximumFrequencyInputValueChanged, true);
            app.MaximumFrequencyInput.Value = 30;
            app.CyclesLabel = uilabel(app.GridLayoutSettingsPanel);
            app.CyclesLabel.Text = 'Number of wavelength cycles:';
            app.xEditFieldLabel = uilabel(app.GridLayoutSettingsPanel);
            app.xEditFieldLabel.Text = 'x';
            app.CyclesInput = uieditfield(app.GridLayoutSettingsPanel, 'numeric');
            app.CyclesInput.Limits = [1 Inf];
            app.CyclesInput.RoundFractionalValues = 'on';
            app.CyclesInput.ValueDisplayFormat = '%.0f';
            app.CyclesInput.ValueChangedFcn = createCallbackFcn(app, @CyclesInputValueChanged, true);
            app.CyclesInput.Value = 7;
            app.DoBaselineNormInput = uicheckbox(app.GridLayoutSettingsPanel);
            app.DoBaselineNormInput.ValueChangedFcn = createCallbackFcn(app, @DoBaselineNormInputValueChanged, true);
            app.DoBaselineNormInput.Enable = 'off';
            app.DoBaselineNormInput.Text = 'Perform baseline normalization';
            app.BaselineNormMethodInput = uidropdown(app.GridLayoutSettingsPanel);
            app.BaselineNormMethodInput.Items = {'Absolute', 'Relative', 'Relative Change', 'Normalized Change', 'Decibel', 'Z-Score'};
            app.BaselineNormMethodInput.ItemsData = {'absolute', 'relative', 'relchange', 'normchange', 'db', 'zscore'};
            app.BaselineNormMethodInput.ValueChangedFcn = createCallbackFcn(app, @BaselineNormMethodInputValueChanged, true);
            app.BaselineNormMethodInput.Enable = 'off';
            app.BaselineNormMethodInput.Value = 'absolute';
            app.BaselineNormMethodDesc = uilabel(app.GridLayoutSettingsPanel);
            app.BaselineNormMethodDesc.Enable = 'off';
            app.BaselineNormMethodDesc.Text = 'No baseline specified';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proc_SpectrogramSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end